classdef Robotmove < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SetRobotsTab                  matlab.ui.container.Tab
        DOBOTTRANSLATIONSLabel        matlab.ui.control.Label
        ZButton_4                     matlab.ui.control.Button
        YButton_4                     matlab.ui.control.Button
        ZButton_3                     matlab.ui.control.Button
        YButton_3                     matlab.ui.control.Button
        XButton_4                     matlab.ui.control.Button
        XButton_3                     matlab.ui.control.Button
        UR3TRANSLATIONSLabel          matlab.ui.control.Label
        ZButton_2                     matlab.ui.control.Button
        YButton_2                     matlab.ui.control.Button
        ZButton                       matlab.ui.control.Button
        YButton                       matlab.ui.control.Button
        XButton_2                     matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        UR3Panel                      matlab.ui.container.Panel
        UJoint6Spinner                matlab.ui.control.Spinner
        UJoint6SpinnerLabel           matlab.ui.control.Label
        UJoint5Spinner                matlab.ui.control.Spinner
        UJoint5SpinnerLabel           matlab.ui.control.Label
        UJoint4Spinner                matlab.ui.control.Spinner
        UJoint4SpinnerLabel           matlab.ui.control.Label
        UJoint3Spinner                matlab.ui.control.Spinner
        UJoint3SpinnerLabel           matlab.ui.control.Label
        UJoint2Spinner                matlab.ui.control.Spinner
        UJoint2SpinnerLabel           matlab.ui.control.Label
        UJoint1Spinner                matlab.ui.control.Spinner
        UJoint1SpinnerLabel           matlab.ui.control.Label
        SetUR3Button                  matlab.ui.control.Button
        DobotPanel                    matlab.ui.container.Panel
        SetDobotButton                matlab.ui.control.Button
        DJoint5Spinner                matlab.ui.control.Spinner
        DJoint5SpinnerLabel           matlab.ui.control.Label
        DJoint4Spinner                matlab.ui.control.Spinner
        DJoint4SpinnerLabel           matlab.ui.control.Label
        DJoint3Spinner                matlab.ui.control.Spinner
        DJoint3SpinnerLabel           matlab.ui.control.Label
        DJoint2Spinner                matlab.ui.control.Spinner
        DJoint2SpinnerLabel           matlab.ui.control.Label
        DJoint1Spinner                matlab.ui.control.Spinner
        DJoint1SpinnerLabel           matlab.ui.control.Label
        ActionsTab                    matlab.ui.container.Tab
        LightcurtainexampleLabel      matlab.ui.control.Label
        ActivateButton_2              matlab.ui.control.Button
        ResumeButton                  matlab.ui.control.Button
        Image4                        matlab.ui.control.Image
        LetBeverageBuddyDisposeofyourrubbishcorrectlyLabel  matlab.ui.control.Label
        GivetoBuddyButton             matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        FinishedLabel                 matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        FetchButton                   matlab.ui.control.Button
        OptionsListBox                matlab.ui.control.ListBox
        OptionsLabel                  matlab.ui.control.Label
        ThirstyLabel                  matlab.ui.control.Label
        PickaDrinkfromtheFridgeLabel  matlab.ui.control.Label
        VisualServoingLabel           matlab.ui.control.Label
        ActivateButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        fanta_qty  = 3 % quantaty of fanta left
        sprite_qty = 3 % quantaty of sprite left
        coke_qty   = 3 % quantaty of coke left
        beer_qty   = 3 % quantaty of beer left
        buddy = BeverageBuddy  % the beverage buddy class
        last_drink = 0  % the last drink to be collected
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetDobotButton
        function SetDobotButtonPushed(app, event)
            %should take input from spinners
            %update the robot
            joints = [app.DJoint1Spinner.Value, app.DJoint2Spinner.Value, app.DJoint3Spinner.Value, app.DJoint4Spinner.Value, app.DJoint5Spinner.Value];
            app.buddy.dobot.setJoints(joints);
        end

        % Button pushed function: SetUR3Button
        function SetUR3ButtonPushed(app, event)
            %should take input from spinners
            %update the robot
            joints = [app.UJoint1Spinner.Value, app.UJoint2Spinner.Value, app.UJoint3Spinner.Value, app.UJoint4Spinner.Value, app.UJoint5Spinner.Value, app.UJoint6Spinner.Value];
            app.buddy.ur3.setJoints(joints);
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            %E-STOP button, PAUSE all work happening -be able to resume
            app.buddy.toggleEstop()
        end

        % Value changed function: OptionsListBox
        function OptionsListBoxValueChanged(app, event)
%              value = app.OptionsListBox.Value;
%    if value == 'COKE'
%        app.Img=imread("cokecan.png");
%    
%             % save as jpg because Image won't accept bmp
%             imwrite(app.Img,"cokecan.png","jpeg");
%             % point the image object at the file
%             app.Img.ImageSource="cokecan.jpg";
%    end
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            % activate robot 'fetch' with selected drink
            
            if app.last_drink ~= 0
                disp('waiting to dispose current drink')
                return
            end
            drink = app.OptionsListBox.Value;

%             disp(drink)
            % checking for drinks remaining and incrementing
            if strcmp(drink, 'FANTA')
                if app.fanta_qty > 0
                    app.buddy.getDrink(1, app.fanta_qty);
                    app.last_drink = [1, app.fanta_qty];
                    app.fanta_qty = app.fanta_qty -1;
                else
                    disp("OUT OF FANTA")
                end
            elseif strcmp(drink, 'SPRITE')
                if app.sprite_qty > 0
                    app.buddy.getDrink(2, app.sprite_qty);
                    app.last_drink = [2, app.sprite_qty];
                    app.sprite_qty = app.sprite_qty -1;
                else
                    disp("OUT OF SPRITE")
                end
            elseif strcmp(drink, 'COKE')
                if app.coke_qty > 0
                    app.buddy.getDrink(3, app.coke_qty);
                    app.last_drink = [3, app.coke_qty];
                    app.coke_qty = app.coke_qty -1;
                else
                    disp("OUT OF COKE")
                end
            elseif strcmp(drink, 'BEER')
                if app.beer_qty > 0
                    app.buddy.getDrink(4, app.beer_qty);
                    app.last_drink = [4, app.beer_qty];
                    app.beer_qty = app.beer_qty -1;
                else
                    disp("OUT OF BEER")
                end
            end
            
        end

        % Button pushed function: GivetoBuddyButton
        function GivetoBuddyButtonPushed(app, event)
            %Activate robot sort for recycling/rubbish
            if app.last_drink == 0
                disp('no drink to collect')
                return
            end
            
            
            % give the drink to the buddy
            app.buddy.giveToBuddy(app.last_drink(1), app.last_drink(2))
            
            % reset the last drink
            app.last_drink = 0;
        end

        % Button pushed function: ActivateButton
        function ActivateButtonPushed(app, event)
            app.buddy.vsExample;
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            % activates the resume flag
            if app.buddy.estop == 0
                app.buddy.resume = 1;
            else
                disp('DISABLE ESTOP')
            end
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            % X+ translation
            app.buddy.translateUR3(transl(0.01,0,0));
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            % X- translation
            app.buddy.translateUR3(transl(-0.01,0,0));
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            % Y+ translation
            app.buddy.translateUR3(transl(0,0.01,0));
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            % Y- translation
            app.buddy.translateUR3(transl(0,-0.01,0));
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            % Z+ translation
            app.buddy.translateUR3(transl(0,0,0.01));
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            % Z- translation
            app.buddy.translateUR3(transl(0,0,-0.01));
        end

        % Button pushed function: XButton_3
        function XButton_3Pushed(app, event)
             % X+ translation
             app.buddy.translateDobot(transl(0.01,0,0));  
        end

        % Button pushed function: XButton_4
        function XButton_4Pushed(app, event)
            % X- translation
            app.buddy.translateDobot(transl(-0.01,0,0));
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            % Y+ translation
            app.buddy.translateDobot(transl(0,0.01,0));
        end

        % Button pushed function: YButton_4
        function YButton_4Pushed(app, event)
            % Y- translation
            app.buddy.translateDobot(transl(0,-0.01,0));
        end

        % Button pushed function: ZButton_3
        function ZButton_3Pushed(app, event)
            % Z+ translation
            app.buddy.translateDobot(transl(0,0,0.01));
        end

        % Button pushed function: ZButton_4
        function ZButton_4Pushed(app, event)
            % Z- translation
            app.buddy.translateDobot(transl(0,0,-0.01));
        end

        % Button pushed function: ActivateButton_2
        function ActivateButton_2Pushed(app, event)
            app.buddy.spawnHand();
            app.buddy.lightCurtainExample();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetRobotsTab = uitab(app.TabGroup);
            app.SetRobotsTab.Title = 'Set Robots';
            app.DobotPanel = uipanel(app.SetRobotsTab);
            app.DobotPanel.Title = 'Dobot';
            app.DJoint1SpinnerLabel = uilabel(app.DobotPanel);
            app.DJoint1SpinnerLabel.Text = 'DJoint 1';
            app.DJoint1Spinner = uispinner(app.DobotPanel);
            app.DJoint2SpinnerLabel = uilabel(app.DobotPanel);
            app.DJoint2SpinnerLabel.Text = 'DJoint 2';
            app.DJoint2Spinner = uispinner(app.DobotPanel);
            app.DJoint3SpinnerLabel = uilabel(app.DobotPanel);
            app.DJoint3SpinnerLabel.Text = 'DJoint 3';
            app.DJoint3Spinner = uispinner(app.DobotPanel);
            app.DJoint4SpinnerLabel = uilabel(app.DobotPanel);
            app.DJoint4SpinnerLabel.Text = 'DJoint 4';
            app.DJoint4Spinner = uispinner(app.DobotPanel);
            app.DJoint5SpinnerLabel = uilabel(app.DobotPanel);
            app.DJoint5SpinnerLabel.Text = 'DJoint 5';
            app.DJoint5Spinner = uispinner(app.DobotPanel);
            app.SetDobotButton = uibutton(app.DobotPanel, 'push');
            app.SetDobotButton.ButtonPushedFcn = createCallbackFcn(app, @SetDobotButtonPushed, true);
            app.SetDobotButton.Text = 'SetDobot';
            app.UR3Panel = uipanel(app.SetRobotsTab);
            app.UR3Panel.Title = 'UR3';
            app.SetUR3Button = uibutton(app.UR3Panel, 'push');
            app.SetUR3Button.ButtonPushedFcn = createCallbackFcn(app, @SetUR3ButtonPushed, true);
            app.SetUR3Button.Text = 'SetUR3';
            app.UJoint1SpinnerLabel = uilabel(app.UR3Panel);
            app.UJoint1SpinnerLabel.Text = 'UJoint 1';
            app.UJoint1Spinner = uispinner(app.UR3Panel);
            app.UJoint2SpinnerLabel = uilabel(app.UR3Panel);
            app.UJoint2SpinnerLabel.Text = 'UJoint 2';
            app.UJoint2Spinner = uispinner(app.UR3Panel);
            app.UJoint3SpinnerLabel = uilabel(app.UR3Panel);
            app.UJoint3SpinnerLabel.Text = 'UJoint 3';
            app.UJoint3Spinner = uispinner(app.UR3Panel);
            app.UJoint4SpinnerLabel = uilabel(app.UR3Panel);
            app.UJoint4SpinnerLabel.Text = 'UJoint 4';
            app.UJoint4Spinner = uispinner(app.UR3Panel);
            app.UJoint5SpinnerLabel = uilabel(app.UR3Panel);
            app.UJoint5SpinnerLabel.Text = 'UJoint 5';
            app.UJoint5Spinner = uispinner(app.UR3Panel);
            app.UJoint6SpinnerLabel = uilabel(app.UR3Panel);
            app.UJoint6SpinnerLabel.Text = 'UJoint6';
            app.UJoint6Spinner = uispinner(app.UR3Panel);
            app.Image = uiimage(app.SetRobotsTab);
            app.Image2 = uiimage(app.SetRobotsTab);
            app.XButton = uibutton(app.SetRobotsTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.SetRobotsTab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X-';
            app.YButton = uibutton(app.SetRobotsTab, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y+';
            app.ZButton = uibutton(app.SetRobotsTab, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z+';
            app.YButton_2 = uibutton(app.SetRobotsTab, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y-';
            app.ZButton_2 = uibutton(app.SetRobotsTab, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = 'Z-';
            app.UR3TRANSLATIONSLabel = uilabel(app.SetRobotsTab);
            app.UR3TRANSLATIONSLabel.Text = 'UR3 TRANSLATIONS';
            app.XButton_3 = uibutton(app.SetRobotsTab, 'push');
            app.XButton_3.ButtonPushedFcn = createCallbackFcn(app, @XButton_3Pushed, true);
            app.XButton_3.Text = 'X+';
            app.XButton_4 = uibutton(app.SetRobotsTab, 'push');
            app.XButton_4.ButtonPushedFcn = createCallbackFcn(app, @XButton_4Pushed, true);
            app.XButton_4.Text = 'X-';
            app.YButton_3 = uibutton(app.SetRobotsTab, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = 'Y+';
            app.ZButton_3 = uibutton(app.SetRobotsTab, 'push');
            app.ZButton_3.ButtonPushedFcn = createCallbackFcn(app, @ZButton_3Pushed, true);
            app.ZButton_3.Text = 'Z+';
            app.YButton_4 = uibutton(app.SetRobotsTab, 'push');
            app.YButton_4.ButtonPushedFcn = createCallbackFcn(app, @YButton_4Pushed, true);
            app.YButton_4.Text = 'Y-';
            app.ZButton_4 = uibutton(app.SetRobotsTab, 'push');
            app.ZButton_4.ButtonPushedFcn = createCallbackFcn(app, @ZButton_4Pushed, true);
            app.ZButton_4.Text = 'Z-';
            app.DOBOTTRANSLATIONSLabel = uilabel(app.SetRobotsTab);
            app.DOBOTTRANSLATIONSLabel.Text = 'DOBOT TRANSLATIONS';
            app.ActionsTab = uitab(app.TabGroup);
            app.ActionsTab.Title = 'Actions';
            app.ActivateButton = uibutton(app.ActionsTab, 'push');
            app.ActivateButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateButtonPushed, true);
            app.ActivateButton.Text = 'Activate';
            app.VisualServoingLabel = uilabel(app.ActionsTab);
            app.VisualServoingLabel.Text = 'Visual Servoing';
            app.PickaDrinkfromtheFridgeLabel = uilabel(app.ActionsTab);
            app.PickaDrinkfromtheFridgeLabel.Text = 'Pick a Drink from the Fridge';
            app.ThirstyLabel = uilabel(app.ActionsTab);
            app.ThirstyLabel.Text = 'Thirsty?';
            app.OptionsLabel = uilabel(app.ActionsTab);
            app.OptionsLabel.Text = ' Options ';
            app.OptionsListBox = uilistbox(app.ActionsTab);
            app.OptionsListBox.Items = {'BEER', 'FANTA', 'SPRITE', 'COKE'};
            app.OptionsListBox.ValueChangedFcn = createCallbackFcn(app, @OptionsListBoxValueChanged, true);
            app.OptionsListBox.Value = 'BEER';
            app.FetchButton = uibutton(app.ActionsTab, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.Image3 = uiimage(app.ActionsTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = 'beerbottle.png';
            app.FinishedLabel = uilabel(app.ActionsTab);
            app.FinishedLabel.Text = 'Finished?';
            app.Label_2 = uilabel(app.ActionsTab);
            app.Label_2.Text = '';
            app.GivetoBuddyButton = uibutton(app.ActionsTab, 'push');
            app.GivetoBuddyButton.ButtonPushedFcn = createCallbackFcn(app, @GivetoBuddyButtonPushed, true);
            app.GivetoBuddyButton.Text = 'Give to Buddy';
            app.LetBeverageBuddyDisposeofyourrubbishcorrectlyLabel = uilabel(app.ActionsTab);
            app.LetBeverageBuddyDisposeofyourrubbishcorrectlyLabel.Text = 'Let Beverage Buddy Dispose of your rubbish correctly';
            app.Image4 = uiimage(app.ActionsTab);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = 'EStopbutton.png';
            app.ResumeButton = uibutton(app.ActionsTab, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.ActivateButton_2 = uibutton(app.ActionsTab, 'push');
            app.ActivateButton_2.ButtonPushedFcn = createCallbackFcn(app, @ActivateButton_2Pushed, true);
            app.ActivateButton_2.Text = 'Activate';
            app.LightcurtainexampleLabel = uilabel(app.ActionsTab);
            app.LightcurtainexampleLabel.Text = 'Light curtain example';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robotmove

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end